classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ACQUISITIONLabel        matlab.ui.control.Label
        ACQUISITIONimage        matlab.ui.control.Image
        PROCESSEDLabel          matlab.ui.control.Label
        PROCESSEDimage          matlab.ui.control.Image
        BLURRINGLEVELSlider     matlab.ui.control.Slider
        LIVESwitch              matlab.ui.control.ToggleSwitch
        LIVESwitchLabel         matlab.ui.control.Label
        UPLOADButton            matlab.ui.control.Button
        CAPTUREButton           matlab.ui.control.Button
        SelectshapeButtonGroup  matlab.ui.container.ButtonGroup
        circleButton            matlab.ui.control.RadioButton
        squareButton            matlab.ui.control.RadioButton
        BLURRINGLEVELLabel      matlab.ui.control.Label
        EXITButton              matlab.ui.control.Button
        REALTIMElabel           matlab.ui.control.Label
        TIMElabel               matlab.ui.control.Label
    end

        %% Definim variabile/proprietati globale pentru a ne usura munca in aplicatie si a evita aparitia duplicatelor nenecesare
        properties (Access = public)
            Camera        % Obiectul pentru camera alocat
            Timer         % Obiectul Timer alocat oentru achizitia continua de informatii/imagini live  
        end
    
        %% Definim functiile folosite in program ulterior
        methods (Access = public)
    
            % functia creata pentru initializarea camerei si a perioadei de achizitie a informatiilor
            function setupLiveAcquisition(app)      
    
                % Initializam obiectul de tip camera              
                app.Camera = webcam('Integrated Camera');  %app.Camera = webcam('ZV-E10');
    
                % Configuram timer-ul pentru achiziționarea continuă a cadrelor, la momente fixe si apeland functia de updateLive
                app.Timer = timer( ...
                    'ExecutionMode', 'fixedRate', ...        % Indică faptul că timer-ul va rula la o frecventa constanta/fixa
                    'Period', .05, ...                       % Frecventa de achizionare a imaginilor ~= 1/20
                    'TimerFcn', @(~,~)updateLive(app), ...   % Specifica functia care va fi rulata la fiecare apelare a timerului
                    'BusyMode', 'drop');                     % Specificam comportamentul timerului atunci cand functia precedenta nu a fost terminata inca, in cazul de fata adaugam intr-o coada si procesam pe rand imaginile
                
                % Start the timer
                start(app.Timer);
            end
        
            function updateLive(app)
    
                % Vericam daca modulul LIVE din UI este pornit de catre utilizator
                if app.LIVESwitch.Value == "On"
    
                    % Selectam forma sub care bluram fata subiectului
                    if app.SelectshapeButtonGroup.SelectedObject == app.squareButton                    
                        % Functia de blurare pentru patrat
                        photoProcessing(app,snapshot(app.Camera));
                    else
                        % Functia de blurare pentru cerc
                        photoProcessing2(app,snapshot(app.Camera));
                    end
                end
    
                %% Update time in label
                % Get the current system time
                currentTime = datetime('now');
            
                % Format the current time as a string
                timeString = datestr(currentTime, 'dd/mm/yyyy HH:MM:SS');
            
                % Update the UI label with the current time
                app.REALTIMElabel.Text = timeString;
            end
    
             
            %% Functia de procasare a achizitiilor, blurare de tip square
            function photoProcessing(app, frame)
        
                % Instantiem/actualizam layerul pentru ACQUISITIONimage cu noul cadru, pt a observa astfel diferenta 
                % dintre imaginea achizionata si mai apoi imaginea prelucrata
                app.ACQUISITIONimage.ImageSource = frame;
                
                % Converti cadrul la grayscale pentru a simplifica procesul de identificare al trasaturilor
                grayFrame = rgb2gray(frame);
                
                % Definim detectorul de fete, 
                faceDetector = vision.CascadeObjectDetector;
                
                % Folosim detectorul si poza in grayscale pentru a extrage locatiile din frame in care detectorul a identificat o fata umana
                bboxes = step(faceDetector, grayFrame);  %  BBOXEX → {N, X, Y, WIDTH, HEIGHT}
                
                % Obtinem nivelul de blur selectat de utilizator din app.BLURRINGLEVELSlider
                blurringLevel = app.BLURRINGLEVELSlider.Value;
                
                % Iteram prin regiuni si aplicam blur pe fiecare regiune detectata
                for j = 1:size(bboxes, 1)
                    % Extragem treptat cate o regiune
                    bbox = bboxes(j, :);
                    
                    % Aplicam un filtru gaussian de reducere a zgomotului/detaliilor dintr-o imagine   
                    blurredRegion = imgaussfilt(frame(bbox(2):bbox(2)+bbox(4), bbox(1):bbox(1)+bbox(3), :), blurringLevel);
                    
                    % Suprapunem in frame-ul original, imaginea blurata/prelucrata
                    frame(bbox(2):bbox(2)+bbox(4), bbox(1):bbox(1)+bbox(3), :) = blurredRegion;
                end
                
                % Actualizam in UI imaginea prelucrata
                app.PROCESSEDimage.ImageSource = frame;
            end
    
        
            %% Functia de procasare a achizitiilor, blurare de tip circle
            function photoProcessing2(app, frame)
                % Actualizam in UI imaginea achizionata
                app.ACQUISITIONimage.ImageSource = frame;
            
                % Convertim cadrul la grayscale
                grayFrame = rgb2gray(frame);
            
                % Definim detectorul de fete
                faceDetector = vision.CascadeObjectDetector;
            
                % Extragem regiunele de interes
                bbox = step(faceDetector, grayFrame);
            
                % Extragem nivelul de blur instantiat de utilizator in app.BLURRINGLEVELSlider
                blurringLevel = app.BLURRINGLEVELSlider.Value;
            
                % Iteram prin regiuni si aplicam blur pe fiecare regiune detectata
                for j = 1:size(bbox, 1)
                    % Extragem treptat cate o regiune
                    faceRect = bbox(j, :);
            
                    % Identificam punctele de mijloc de unde incep trasaturile umane
                    circleCenterX = faceRect(1) + faceRect(3) / 2;          % Calculam centrul pe latime
                    circleCenterY = faceRect(2) + faceRect(4) / 2;          % Calculam centrul pe inaltime
                    circleRadius = max(faceRect(3) / 2, faceRect(4) / 2);   % Selectam cazul in care acoperim cat mai mult din fata
            
                    % Ne asiguram ca raza carcului nu depaseste marginea detectata
                    circleRadius = min(circleRadius, min(circleCenterX, circleCenterY));
                    circleRadius = min(circleRadius, min(size(frame, 2) - circleCenterX, size(frame, 1) - circleCenterY));
            
                    % Aplicam filtrul gaussian pe zona in cauza
                    blurredRegion = imgaussfilt(frame, blurringLevel);    
    
                    % Cream o masca circlara pentru a putea extrage din zona blurata o forma sferica
                    [x, y] = meshgrid(1:size(frame, 2), 1:size(frame, 1));  
                    mask = sqrt((x - circleCenterX).^2 + (y - circleCenterY).^2) <= circleRadius;
            
                    % Aplicam regiunii masca de filtrare, [1,1] replicarea mastii pe X si Y, iar 3 pt replicarea pe toate cele 3 canale    
                    frame(repmat(mask, [1, 1, 3])) = blurredRegion(repmat(mask, [1, 1, 3]));
                end
            
                % Actualizam in UI imaginea prelucrata
                app.PROCESSEDimage.ImageSource = frame;
            end
    
    
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            set(app.UIFigure, 'Name', 'PROIECT SVA - TEMA 17 - BUTACU IONEL-CATALIN 1305B');
            setupLiveAcquisition(app);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
                delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
                EXITButtonPushed(app, event)
        end

        % Button pushed function: CAPTUREButton
        function CAPTUREButtonPushed(app, event)
                % Vericam daca modulul LIVE din UI este pornit de catre utilizator
                if app.LIVESwitch.Value == "On"
                    % Avertizam userul ca nu poate executa prelucrari individual daca livemode este activ
                    uialert(app.UIFigure, 'Cannot capture in live mode.', 'Warning', 'Icon', 'warning');                
                else    
                    % Achizionam un cadru de la camera
                    frame = snapshot(app.Camera);
    
                    % Selectam forma sub care bluram fata subiectului
                    if app.SelectshapeButtonGroup.SelectedObject == app.squareButton                    
                        % Functia de blurare pentru patrat
                        photoProcessing(app,frame);
                    else
                        % Functia de blurare pentru cerc
                        photoProcessing2(app,frame);
                    end
                end          
        end

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
                % Vericam daca modulul LIVE din UI este pornit de catre utilizator
                if app.LIVESwitch.Value == "On"
                    % Avertizam userul ca nu poate executa prelucrari individual daca livemode este activ
                    uialert(app.UIFigure, 'Cannot upload in live mode.', 'Warning', 'Icon', 'warning');
                else
                    % Deschide o fereastră dialog pentru selectarea unei imagini 
                    [file, path] = uigetfile({'*.jpg;*.png'}, 'Select an image file');
    
                    % Verificam daca fisierul este valid
                    if file == 0
                        return;
                    end
        
                    % Achizionam un cadru
                    imagePath = fullfile(path, file);
                    frame = imread(imagePath);    
    
                    % Selectam forma sub care bluram fata subiectului
                    if app.SelectshapeButtonGroup.SelectedObject == app.squareButton                    
                        % Functia de blurare pentru patrat
                        photoProcessing(app,frame);
                    else
                        % Functia de blurare pentru cerc
                        photoProcessing2(app,frame);
                    end
                end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
                %{
                originalResolution = app.Position;        
                currentSize = app.UIFigure.Position;        
                scalingFactor = min(currentSize ./ originalResolution);
             
                components = findobj(app.UIFigure, '-property', 'FontSize');
                for i = 1:numel(components)
                    components(i).FontSize = components(i).FontSize * scalingFactor;
                    components(i).Position = components(i).Position * scalingFactor;
                end
            
                % Manually scale images
                app.ACQUISITIONimage.ScaleMethod = 'manual';
                app.ACQUISITIONimage.ScaleFactor = scalingFactor;
                app.PROCESSEDimage.ScaleMethod = 'manual';
                app.PROCESSEDimage.ScaleFactor = scalingFactor;
            
                drawnow('limitrate');
                %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TIMElabel = uilabel(app.UIFigure);
            app.TIMElabel.Text = 'Real time:';
            app.REALTIMElabel = uilabel(app.UIFigure);
            app.REALTIMElabel.Text = 'zz/ll/aaaa hh:mm:ss';
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.BLURRINGLEVELLabel = uilabel(app.UIFigure);
            app.BLURRINGLEVELLabel.Text = 'BLURRING LEVEL:';
            app.SelectshapeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectshapeButtonGroup.Title = 'Select shape';
            app.squareButton = uiradiobutton(app.SelectshapeButtonGroup);
            app.squareButton.Text = 'square';
            app.squareButton.Value = true;
            app.circleButton = uiradiobutton(app.SelectshapeButtonGroup);
            app.circleButton.Text = 'circle';
            app.CAPTUREButton = uibutton(app.UIFigure, 'push');
            app.CAPTUREButton.ButtonPushedFcn = createCallbackFcn(app, @CAPTUREButtonPushed, true);
            app.CAPTUREButton.Text = 'Capture';
            app.UPLOADButton = uibutton(app.UIFigure, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.LIVESwitchLabel = uilabel(app.UIFigure);
            app.LIVESwitchLabel.Text = 'LIVE';
            app.LIVESwitch = uiswitch(app.UIFigure, 'toggle');
            app.BLURRINGLEVELSlider = uislider(app.UIFigure);
            app.BLURRINGLEVELSlider.Limits = [0 40];
            app.BLURRINGLEVELSlider.Value = 10;
            app.PROCESSEDimage = uiimage(app.UIFigure);
            app.PROCESSEDLabel = uilabel(app.UIFigure);
            app.PROCESSEDLabel.Text = 'LIVE IMAGE PROCESSED';
            app.ACQUISITIONimage = uiimage(app.UIFigure);
            app.ACQUISITIONLabel = uilabel(app.UIFigure);
            app.ACQUISITIONLabel.Text = 'LIVE IMAGE ACQUISITION';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end